classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PreviewTextAreaLabel  matlab.ui.control.Label
        Preview               matlab.ui.control.TextArea
        LoadButton            matlab.ui.control.Button
        CalcButton            matlab.ui.control.Button
        SaveButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global data;
            [FileID, PathID] = uigetfile('*.xlsx');
            figure(app.UIFigure);
            path = strcat(PathID, FileID);
            if FileID
                data = fillmissing(readmatrix(path), 'constant', 0);
                if isvector(data)
                    outstring = string(data);
                else
                    outstring = join(string(data), '    ');
                end
                app.Preview.Value = outstring;
                app.CalcButton.Enable = true;
            end
        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            global data;
            for i = 1:size(data(:,1))
                minCount = 1;
                for j = (i-1):-1:1
                    if data(i,1) < data(j,1)
                        minCount = minCount+1;
                    else
                        break;
                    end
                end
                data(i,2) = minCount;
            end
            app.Preview.Value = join(string(data), '    ');
            app.SaveButton.Enable = true;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global data;
            [FileID, PathID] = uiputfile('AppTable_.xlsx');
            figure(app.UIFigure);
            path = strcat(PathID, FileID);
            if FileID
                writetable(array2table(data), path, 'WriteVariableNames', false, 'AutoFitWidth', false);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreviewTextAreaLabel = uilabel(app.UIFigure);
            app.PreviewTextAreaLabel.Text = 'Preview';
            app.Preview = uitextarea(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CalcButton = uibutton(app.UIFigure, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Enable = 'off';
            app.CalcButton.Text = 'Calc';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end