classdef App_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        sinCheckBox  matlab.ui.control.CheckBox
        cosCheckBox  matlab.ui.control.CheckBox
        calcButton   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calcButton
        function calcButtonPushed(app, event)
            x = -5:0.1:5;
            if app.sinCheckBox.Value
                plot(app.UIAxes, sin(x));
            end
            if app.cosCheckBox.Value
                plot(app.UIAxes, cos(x));
            end
            if app.sinCheckBox.Value && app.cosCheckBox.Value
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, sin(x));
                plot(app.UIAxes, sin(x));
                hold(app.UIAxes, 'off');
            end
            if not (app.sinCheckBox.Value | app.cosCheckBox.Value)
                cla(app.UIAxes);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sinCheckBox = uicheckbox(app.UIFigure);
            app.sinCheckBox.Text = 'sin';
            app.cosCheckBox = uicheckbox(app.UIFigure);
            app.cosCheckBox.Text = 'cos';
            app.calcButton = uibutton(app.UIFigure, 'push');
            app.calcButton.ButtonPushedFcn = createCallbackFcn(app, @calcButtonPushed, true);
            app.calcButton.Text = 'calc';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end